classdef check_assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes1               matlab.ui.control.UIAxes
        TitleLabel            matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        NeuronIDSpinnerLabel  matlab.ui.control.Label
        NeuronIDSpinner       matlab.ui.control.Spinner
        LoadImagesButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        calcium_volume
        multicolor_volume
        
        calcium_max_proj
        multicolor_max_proj
        
        calcium_points
        multicolor_points
        
        calcium_to_multicolor_assignments
    end
    
    methods (Access = private)
        function plot_slice(app)
            neuron_ID = app.NeuronIDSpinner.Value;
            
            imagesc(app.UIAxes1, app.calcium_max_proj);
            hold(app.UIAxes1, 'on');
            scatter(app.UIAxes1, app.calcium_points(neuron_ID, 1), app.calcium_points(neuron_ID, 2))
            hold(app.UIAxes1, 'off');
            colormap(app.UIAxes1, gray);
            axis(app.UIAxes1, 'equal')
            
            imagesc(app.UIAxes2, app.multicolor_max_proj);
            if app.calcium_to_multicolor_assignments(neuron_ID) ~= 0
                hold(app.UIAxes2, 'on');
                scatter(app.UIAxes2, app.multicolor_points(app.calcium_to_multicolor_assignments(neuron_ID), 1), app.multicolor_points(app.calcium_to_multicolor_assignments(neuron_ID), 2))
                hold(app.UIAxes2, 'off');
            end
            colormap(app.UIAxes2, gray);
            axis(app.UIAxes2, 'equal')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: NeuronIDSpinner
        function NeuronIDSpinnerValueChanged(app, event)
            app.plot_slice();
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            % calcium
%             calcium_folder = uigetdir([], 'select the calcium imaging folder');
            calcium_folder = '/home/mcreamer/Documents/data_sets/panneuronal/BrainScanner20200130_145049';

            % multicolor
%             multicolor_folder = uigetdir([], 'select the multicolor imaging folder');
            multicolor_folder = '/home/mcreamer/Documents/data_sets/neuropal/creamer/20200130/multicolorworm_20200130_145049';
    
            %% load assignment data
            alignment = load(fullfile(calcium_folder, 'calcium_to_neuropal_alignment.mat'));
            
            app.calcium_points = alignment.fixed_initial / 0.42;
            [~, sort_ids] = sort(app.calcium_points(:, 2), 1, 'descend');
            app.calcium_points = app.calcium_points(sort_ids, :);
            app.multicolor_points = alignment.adjusted_initial / 0.42;
            app.calcium_to_multicolor_assignments = alignment.new_assignments;
            app.calcium_to_multicolor_assignments = app.calcium_to_multicolor_assignments(sort_ids, :);
            
            app.NeuronIDSpinner.Limits = [1, length(app.calcium_to_multicolor_assignments)];
            
            %% load the calcium data
            calcium_in = load(fullfile(calcium_folder, 'calcium_data_average_stack.aml'), '-mat');
            app.calcium_volume = calcium_in.data(:, :, :, 4);
            
            %% load the multicolor data
            multicolor_in = load(fullfile(multicolor_folder, 'neuropal_data.mat'));
            app.multicolor_volume = multicolor_in.data(:, :, :, 4);

            %% remove this later this is for demonstration
            app.multicolor_volume = permute(app.multicolor_volume, [2, 1, 3]);
            app.multicolor_volume = flipud(app.multicolor_volume);
            app.multicolor_points = app.multicolor_points(:, [2, 1, 3]);
            app.multicolor_points(:, 2) = -(app.multicolor_points(:, 2) - size(app.multicolor_volume, 1)/2) + size(app.multicolor_volume, 1)/2;
            
            app.calcium_max_proj = max(app.calcium_volume, [], 3);
            app.multicolor_max_proj = max(app.multicolor_volume, [], 3);
            
            app.plot_slice();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.NeuronIDSpinnerLabel = uilabel(app.UIFigure);
            app.NeuronIDSpinnerLabel.Text = 'Neuron ID';
            app.NeuronIDSpinner = uispinner(app.UIFigure);
            app.NeuronIDSpinner.Limits = [1 Inf];
            app.NeuronIDSpinner.ValueChangedFcn = createCallbackFcn(app, @NeuronIDSpinnerValueChanged, true);
            app.NeuronIDSpinner.Value = 1;
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end